classdef Scoring < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Label_2                   matlab.ui.control.Label
        SkipButton                matlab.ui.control.Button
        NegativeButton            matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        NameofConditionEditField  matlab.ui.control.EditField
        NameofConditionLabel      matlab.ui.control.Label
        RunButton                 matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        BrowseButton              matlab.ui.control.Button
        DiffusedButton            matlab.ui.control.Button
        FocalButton               matlab.ui.control.Button
        ImageD                    matlab.ui.control.Image
        ImageM                    matlab.ui.control.Image
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        c_cnt = 0;
        roi_cnt = [];
        channel = [];
        crit
        Flag
        c_names = {};
        paths = {};
        files = {};
        map = {};
        call_seq
        datalog
    end
    
    methods (Access = public)
        
        function mouse_show(app)
            app.ImageM.Visible = 'on';
        end

        function mouse_hide(app)
            app.ImageM.Visible = 'off';
        end

        function mouse_talk(app,txt)
            app.ImageD.Visible = 'on';
            app.Label_2.Text = txt;
        end

        function mouse_mute(app)
            app.ImageD.Visible = 'off';
            app.Label_2.Text = '';
        end

        function out = group_sets(app,path)
            cd(path)
            tiffs = struct2table(dir("*.tif"));
            ROIsets = struct2table(dir("*.zip"));
            m = height(ROIsets); n = height(tiffs);
            if m==1
                tiffs.name = {tiffs.name};
            end
            if n==1
                ROIsets.name = {ROIsets.name};
            end
            if m~=n
                disp(['Warning: the number of TIFF files does' ...
                      ' not match the number of ROI sets!']);
            end
            TIFF_names = erase(tiffs.name,'.tif');
            ROI_names = cell(size(TIFF_names));
            for i = 1:n
                for j = 1:m
                    if strncmpi(ROIsets.name{j},TIFF_names{i},length(TIFF_names{i}))
                        ROI_names(i) = ROIsets.name(j);
                        break
                    end
                end
            end
            out = [TIFF_names,ROI_names];
        end

        function set_channel(app)
            prompt = {'Select channel to evaluate:'};
            dlgtitle = 'Select channel';
            fieldsize = [1 45];
            definput = {'2'};
            app.channel = [app.channel,...
                           str2double(inputdlg(prompt,dlgtitle,fieldsize,definput))];
        end

        function out = read_movie(app,path,file)
            cd(path)
            mov_name = [file,'.tif'];
            movie = bigread2(mov_name,1);
            out = movie;
        end

        function out = read_rois(app,path,file)
            cd(path)
            out = ReadImageJROI(file);
        end

        function out = create_map(app,img,rois,expand)
            mask = zeros(size(img)+2*expand);
            mask(expand+1:end-expand,expand+1:end-expand) = img;
            group = zeros(length(rois),1);
            location = repmat([1,1,2*expand-1,2*expand-1],length(rois),1);
            contour = cell(length(rois),1);
            name = cell(length(rois),1);
            for k = 1:length(rois)
                group(k) = rois{k}.nStrokeColor;
                location(k,:) = location(k,:)+rois{k}.vnRectBounds;
                contour(k) = {rois{k}.mnCoordinates+repmat(expand,...
                              size(rois{k}.mnCoordinates))};
                name(k) = {rois{k}.strName};
            end
            out = {mask,group,location,contour,name};
        end

        function score(app)
            mouse_hide(app)
            mouse_mute(app)
            for i = 1:height(app.datalog)
                app.Label.Text = [num2str(i),' out of ',num2str(length(app.call_seq))];
                roi_called = app.call_seq(i);
                app.datalog(roi_called,2) = i;
                cond = app.datalog(roi_called,3);
                set = app.datalog(roi_called,4);
                data = app.map{cond};
                data = data(set,:);
                
                img = data{1};

                loc = data{3};
                loc = loc(app.datalog(roi_called,5),:);
                contour = data{4};
                contour = contour(app.datalog(roi_called,5));
                contour = cell2mat(contour);
                contour = [contour;contour(1,:)];
                cropped_img = img(loc(1):loc(3),loc(2):loc(4));
                
                axis(app.UIAxes,'tight')
                imagesc(app.UIAxes,cropped_img)
                hold(app.UIAxes,'on')
                plot(app.UIAxes,contour(:,1)-loc(2)+1,contour(:,2)-loc(1)+1,'r')
                hold(app.UIAxes,'off')
                
                app.Flag = false;
                while(app.Flag == false) 
                    pause(0.5);
                end

                app.datalog(roi_called,7) = app.crit;

            end
            cla(app.UIAxes)
        end

        function save_results(app)
            Index = app.datalog(:,1);
            
            ROI_Called_Sequence = app.datalog(:,2);
            Condition_Index = app.datalog(:,3);
            Set_Index = app.datalog(:,4);
            Within_Set_Index = app.datalog(:,5);
            Group_Identifier = app.datalog(:,6);
            Score = app.datalog(:,7);

            ROI_name = cell(height(app.datalog),1);
            Condition_Name = cell(height(app.datalog),1);
            for i = 1:height(app.datalog)
                cond = app.datalog(i,3);
                roinf = app.map{cond};
                set = app.datalog(i,4);
                data = roinf(set,:);
                names = data{:,5};
                ROI_name(i) = names(app.datalog(i,5),:);
                Condition_Name(i) = app.c_names(cond);
            end
            data2save = table(Index,ROI_name,ROI_Called_Sequence,...
                              Condition_Index,Condition_Name,Set_Index,...
                              Within_Set_Index,Group_Identifier,Score);
            path = uigetdir('','Select the folder to score');
            if isequal(path, 0)
                path = app.paths{1};
            end
            cd(path)
            save 'output.mat' data2save
            mouse_show(app)
            mouse_talk(app,'All set!')
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            mouse_talk(app,'Getting ready?')
            if isdeployed 
			    % User is running an executable in standalone mode. 
			    [~ , result] = system('set PATH');
			    executableFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
		    else
			    % User is running an m-file from the MATLAB integrated development environment (regular MATLAB).
                tmp_path = mfilename('fullpath');
                [executableFolder , ~ , ~] = fileparts(tmp_path);
            end
            addpath(executableFolder)
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            path = uigetdir('','Select the folder to score');
            if isequal(path, 0)
                return
            else
                app.paths = [app.paths;path];
                app.c_cnt = app.c_cnt+1;
                c_name = app.NameofConditionEditField.Value;
                if any(any(strcmp(app.c_names,c_name))) || isempty(c_name)
                    c_name = num2str(app.c_cnt);
                    app.c_names = [app.c_names;c_name];
                else
                    app.c_names = [app.c_names;c_name];
                end
                out = group_sets(app,path);
                app.files = [app.files,{out}];
                set_channel(app)

                newrow = {app.c_cnt,c_name,height(out),app.channel(app.c_cnt)};
                app.UITable.Data=[app.UITable.Data;newrow];

            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            cnroi = [];
            for i = 1:app.c_cnt % sweep through all conditions
                sets = app.files{i};
                mp = cell(height(sets),5);
                for j = 1:height(sets) % sweep through all sets
                    % load frame
                    img = read_movie(app,app.paths{i},sets{j,1});
                    img = img(:,:,app.channel(i));
                    % read ROIs
                    rois = read_rois(app,app.paths{i},sets{j,2});
                    app.roi_cnt = [app.roi_cnt,length(rois)];
                    % Create roi map
                    expand = 10;
                    mp(j,:) = create_map(app,img,rois,expand);
                end
                cnroi = [cnroi,sum(app.roi_cnt)];
                app.map = [app.map,{mp}];
            end

            %%
            app.datalog = zeros(sum(app.roi_cnt),7);
            app.datalog(:,1) = (1:sum(app.roi_cnt));
            app.call_seq = randperm(sum(app.roi_cnt))';
            % determine condition
            for i = 1:app.c_cnt
                if i==1
                    app.datalog(1:cnroi(i),3) = i;
                else
                    app.datalog(cnroi(i-1)+1:cnroi(i),3) = i;
                end
            end
            % determine groups (from roi stroke color) & log set
            gp = [];
            st = [];
            loc = [];
            for i = 1:app.c_cnt % sweep through all conditions
                sets = app.map{i};
                for j = 1:height(sets) % sweep through all sets
                    gp = [gp;sets{j,2}];
                    st = [st;repmat(j,height(sets{j,2}),1)];
                    loc = [loc;(1:height(sets{j,2}))'];
                end
            end
            app.datalog(:,4) = st;
            app.datalog(:,5) = loc;
            app.datalog(:,6) = gp;
            
            % run scoring
            score(app)
            
            % save datalog, if nothing else
            save_results(app)
            
        end

        % Callback function
        function UITableDisplayDataChanged(app, event)
            
        end

        % Button pushed function: FocalButton
        function FocalButtonPushed(app, event)
            app.Flag = true;
            app.crit = 1;
        end

        % Button pushed function: DiffusedButton
        function DiffusedButtonPushed(app, event)
            app.Flag = true;
            app.crit = 2;
        end

        % Button pushed function: NegativeButton
        function NegativeButtonPushed(app, event)
            app.Flag = true;
            app.crit = 3;
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            app.Flag = true;
            app.crit = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.CLim = [0 4095];
            app.UIAxes.XAxisLocation = 'top';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            colormap(app.UIAxes, 'gray')
            app.ImageM = uiimage(app.UIFigure);
            app.ImageM.ImageSource = fullfile(pathToMLAPP, 'mouse.png');
            app.ImageD = uiimage(app.UIFigure);
            app.ImageD.Visible = 'off';
            app.ImageD.ImageSource = fullfile(pathToMLAPP, 'bubble.png');
            app.FocalButton = uibutton(app.UIFigure, 'push');
            app.FocalButton.ButtonPushedFcn = createCallbackFcn(app, @FocalButtonPushed, true);
            app.FocalButton.Text = 'Focal';
            app.DiffusedButton = uibutton(app.UIFigure, 'push');
            app.DiffusedButton.ButtonPushedFcn = createCallbackFcn(app, @DiffusedButtonPushed, true);
            app.DiffusedButton.Text = 'Diffused';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Condition #'; 'Name'; 'n Sets'; 'Channel'};
            app.UITable.RowName = {};
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NameofConditionLabel = uilabel(app.UIFigure);
            app.NameofConditionLabel.Text = 'Name of Condition:';
            app.NameofConditionEditField = uieditfield(app.UIFigure, 'text');
            app.NameofConditionEditField.Value = 'Condition A';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.NegativeButton = uibutton(app.UIFigure, 'push');
            app.NegativeButton.ButtonPushedFcn = createCallbackFcn(app, @NegativeButtonPushed, true);
            app.NegativeButton.Text = 'Negative';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Scoring

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end